classdef game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RANDOMNUMBERGUESSINGGAMELabel  matlab.ui.control.Label
        QUITButton                     matlab.ui.control.Button
        PLAYAGAINButton                matlab.ui.control.Button
        RESULTEditField                matlab.ui.control.EditField
        RESULTEditFieldLabel           matlab.ui.control.Label
        PLAYERNAMEEditField            matlab.ui.control.EditField
        PLAYERNAMEEditFieldLabel       matlab.ui.control.Label
        ROUNDEditField                 matlab.ui.control.NumericEditField
        ROUNDEditFieldLabel            matlab.ui.control.Label
        USERSCOREEditField_2           matlab.ui.control.NumericEditField
        USERSCOREEditField_2Label      matlab.ui.control.Label
        COMPUTERSCOREEditField         matlab.ui.control.NumericEditField
        COMPUTERSCOREEditFieldLabel    matlab.ui.control.Label
        GENERATOREditField             matlab.ui.control.NumericEditField
        GENERATOREditFieldLabel        matlab.ui.control.Label
        CHECKButton                    matlab.ui.control.Button
        EditField                      matlab.ui.control.NumericEditField
        NUMBEROFROUNDSEditField        matlab.ui.control.NumericEditField
        NUMBEROFROUNDSEditFieldLabel   matlab.ui.control.Label
        BESTSCOREEditField             matlab.ui.control.NumericEditField
        BESTSCOREEditFieldLabel        matlab.ui.control.Label
        BATTLEWITHAILabel              matlab.ui.control.Label
        Image                          matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CHECKButton
        function CHECKButtonPushed(app, event)
            n=app.EditField.Value;
            while app.ROUNDEditField.Value<app.NUMBEROFROUNDSEditField.Value
                nr=randi([1,100],1,1);
                app.GENERATOREditField.Value=nr;
                app.ROUNDEditField.Value=app.ROUNDEditField.Value+1;
                if n>nr 
                    app.USERSCOREEditField_2.Value=app.USERSCOREEditField_2.Value+1;
                    break;
                elseif n<nr 
                    app.COMPUTERSCOREEditField.Value=app.COMPUTERSCOREEditField.Value+1;
                    break;
                else
                    app.USERSCOREEditField_2.Value=app.USERSCOREEditField_2.Value+1;
                    app.COMPUTERSCOREEditField.Value=app.COMPUTERSCOREEditField.Value+1;
                    break;
                end
            end
            if (app.ROUNDEditField.Value +1)>app.NUMBEROFROUNDSEditField.Value
                if app.USERSCOREEditField_2.Value>app.COMPUTERSCOREEditField.Value
                    app.RESULTEditField.Value=sprintf('CONGRATULATIONS! %s FOR WINNING',app.PLAYERNAMEEditField.Value);
                elseif app.USERSCOREEditField_2.Value<app.COMPUTERSCOREEditField.Value
                    app.RESULTEditField.Value=sprintf('CONGRATULATIONS! COMPUTER FOR WINNING');
                else
                    app.RESULTEditField.Value=sprintf("IT'S A TIE!");
                end
            end
            if (app.ROUNDEditField.Value +1)>app.NUMBEROFROUNDSEditField.Value
                if  app.BESTSCOREEditField.Value<app.USERSCOREEditField_2.Value
                    app.BESTSCOREEditField.Value=app.USERSCOREEditField_2.Value;
                end
            end
        end

        % Button pushed function: PLAYAGAINButton
        function PLAYAGAINButtonPushed(app, event)
            app.NUMBEROFROUNDSEditField.Value=0;
            app.USERSCOREEditField_2.Value=0;
            app.COMPUTERSCOREEditField.Value=0;
            app.ROUNDEditField.Value=0;
            app.RESULTEditField.Value='';
            app.GENERATOREditField.Value=0;
            app.EditField.Value=0;
        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            app.NUMBEROFROUNDSEditField.Value=app.ROUNDEditField.Value;
            n=app.EditField.Value;
            while app.ROUNDEditField.Value<app.NUMBEROFROUNDSEditField.Value
                nr=randi([1,100],1,1);
                app.GENERATOREditField.Value=nr;
                app.ROUNDEditField.Value=app.ROUNDEditField.Value+1;
                if n>nr 
                    app.USERSCOREEditField_2.Value=app.USERSCOREEditField_2.Value+1;
                    break;
                elseif n<nr 
                    app.COMPUTERSCOREEditField.Value=app.COMPUTERSCOREEditField.Value+1;
                    break;
                else
                    app.USERSCOREEditField_2.Value=app.USERSCOREEditField_2.Value+1;
                    app.COMPUTERSCOREEditField.Value=app.COMPUTERSCOREEditField.Value+1;
                    break;
                end
            end
            if (app.ROUNDEditField.Value +1)>app.NUMBEROFROUNDSEditField.Value
                if app.USERSCOREEditField_2.Value>app.COMPUTERSCOREEditField.Value
                    app.RESULTEditField.Value=sprintf('CONGRATULATIONS! %s FOR WINNING',app.PLAYERNAMEEditField.Value);
                elseif app.USERSCOREEditField_2.Value<app.COMPUTERSCOREEditField.Value
                    app.RESULTEditField.Value=sprintf('CONGRATULATIONS! COMPUTER FOR WINNING');
                else
                    app.RESULTEditField.Value=sprintf("IT'S A TIE!");
                end
            end
            if (app.ROUNDEditField.Value +1)>app.NUMBEROFROUNDSEditField.Value
                if  app.BESTSCOREEditField.Value<app.USERSCOREEditField_2.Value
                    app.BESTSCOREEditField.Value=app.USERSCOREEditField_2.Value;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '16329823_Kerfin7_NEA_2341.jpg');
            app.BATTLEWITHAILabel = uilabel(app.UIFigure);
            app.BATTLEWITHAILabel.Text = 'BATTLE WITH AI';
            app.BESTSCOREEditFieldLabel = uilabel(app.UIFigure);
            app.BESTSCOREEditFieldLabel.Text = 'BEST SCORE';
            app.BESTSCOREEditField = uieditfield(app.UIFigure, 'numeric');
            app.NUMBEROFROUNDSEditFieldLabel = uilabel(app.UIFigure);
            app.NUMBEROFROUNDSEditFieldLabel.Text = 'NUMBER OF ROUNDS';
            app.NUMBEROFROUNDSEditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.CHECKButton = uibutton(app.UIFigure, 'push');
            app.CHECKButton.ButtonPushedFcn = createCallbackFcn(app, @CHECKButtonPushed, true);
            app.CHECKButton.Text = 'CHECK';
            app.GENERATOREditFieldLabel = uilabel(app.UIFigure);
            app.GENERATOREditFieldLabel.Text = 'GENERATOR';
            app.GENERATOREditField = uieditfield(app.UIFigure, 'numeric');
            app.COMPUTERSCOREEditFieldLabel = uilabel(app.UIFigure);
            app.COMPUTERSCOREEditFieldLabel.Text = 'COMPUTER SCORE';
            app.COMPUTERSCOREEditField = uieditfield(app.UIFigure, 'numeric');
            app.USERSCOREEditField_2Label = uilabel(app.UIFigure);
            app.USERSCOREEditField_2Label.Text = 'USER SCORE';
            app.USERSCOREEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ROUNDEditFieldLabel = uilabel(app.UIFigure);
            app.ROUNDEditFieldLabel.Text = 'ROUND';
            app.ROUNDEditField = uieditfield(app.UIFigure, 'numeric');
            app.PLAYERNAMEEditFieldLabel = uilabel(app.UIFigure);
            app.PLAYERNAMEEditFieldLabel.Text = 'PLAYER NAME';
            app.PLAYERNAMEEditField = uieditfield(app.UIFigure, 'text');
            app.RESULTEditFieldLabel = uilabel(app.UIFigure);
            app.RESULTEditFieldLabel.Text = 'RESULT';
            app.RESULTEditField = uieditfield(app.UIFigure, 'text');
            app.PLAYAGAINButton = uibutton(app.UIFigure, 'push');
            app.PLAYAGAINButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYAGAINButtonPushed, true);
            app.PLAYAGAINButton.Text = 'PLAY AGAIN';
            app.QUITButton = uibutton(app.UIFigure, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.RANDOMNUMBERGUESSINGGAMELabel = uilabel(app.UIFigure);
            app.RANDOMNUMBERGUESSINGGAMELabel.Text = '-----RANDOM NUMBER GUESSING GAME-----';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end